classdef Weather_Base_Station02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Sea                        matlab.ui.control.Label
        SL                         matlab.ui.control.LinearGauge
        AltitudeLabel              matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        PocketWeatherStationLabel  matlab.ui.control.Label
        Air                        matlab.ui.control.Label
        Humi                       matlab.ui.control.Label
        TempF                      matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        NPICLabel                  matlab.ui.control.Label
        PWTLabel                   matlab.ui.control.Label
        AP                         matlab.ui.control.SemicircularGauge
        AirPressureGaugeLabel      matlab.ui.control.Label
        Hm                         matlab.ui.control.SemicircularGauge
        HumidityGaugeLabel         matlab.ui.control.Label
        CLabel                     matlab.ui.control.Label
        Temp                       matlab.ui.control.Label
        F                          matlab.ui.control.SemicircularGauge
        FGaugeLabel                matlab.ui.control.Label
        NationalPolytechnicInstituteofCambodiaLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        Label_3                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        arduinoObj % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear   pws;    clear   data;
            pws = serialport('COM11',9600);
            fileloc = fopen('Data.csv','a+');
%             fprintf (fileloc, "%s,%s,%s,%s,%s\n",'Temperature','TempF','Humidity','Air Pressure','Altitude');
            fprintf (fileloc, "%s,%s,%s,%s,%s\n",'','','','','');
            while true
                data = (split(pws.readline(),','));
                pws1 = data(1); %Temp
                pws2 = data(2); %Air Pressure
                pws3 = data(3); %Altitude
                pws4 = data(4); %Humidity
                pws5 = data(5); %TempF
                
                temp = str2double(pws1);
                air = str2double(pws2);
                altitude = str2double(pws3);
                humidity = str2double(pws4);
                tempf = str2double(pws5);
                
                app.Temp.Text = pws1;
                app.Air.Text = pws2;
                app.Sea.Text = pws3;
                app.Humi.Text = pws4;
                app.TempF.Text = pws5;
                
                app.F.Value = tempf;
                app.AP.Value = air;
                app.SL.Value = altitude;
                app.Hm.Value = humidity;
                
                fprintf (fileloc,"%f,%f,%f,%f,%f\n",temp,tempf,humidity,air,altitude);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = ' ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = ' ';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = ' ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Blue Minimalist Pixel Art Motivational Quote Phone Wallpaper (2).png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'unnamed.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'វិទ្យាស្ថានជាតិពហុបច្ចេកទេសកម្ពុជា';
            app.NationalPolytechnicInstituteofCambodiaLabel = uilabel(app.UIFigure);
            app.NationalPolytechnicInstituteofCambodiaLabel.Text = 'National Polytechnic Institute of Cambodia';
            app.FGaugeLabel = uilabel(app.UIFigure);
            app.FGaugeLabel.Text = '°F';
            app.F = uigauge(app.UIFigure, 'semicircular');
            app.F.Limits = [-20 160];
            app.F.Value = -20;
            app.Temp = uilabel(app.UIFigure);
            app.Temp.Text = 'C';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = '°C';
            app.HumidityGaugeLabel = uilabel(app.UIFigure);
            app.HumidityGaugeLabel.Text = 'Humidity';
            app.Hm = uigauge(app.UIFigure, 'semicircular');
            app.Hm.ScaleDirection = 'counterclockwise';
            app.Hm.Value = 100;
            app.AirPressureGaugeLabel = uilabel(app.UIFigure);
            app.AirPressureGaugeLabel.Text = 'Air-Pressure';
            app.AP = uigauge(app.UIFigure, 'semicircular');
            app.AP.Limits = [0 1500];
            app.PWTLabel = uilabel(app.UIFigure);
            app.PWTLabel.Text = 'PWT';
            app.NPICLabel = uilabel(app.UIFigure);
            app.NPICLabel.Text = '(NPIC)';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '09/06/2022';
            app.TempF = uilabel(app.UIFigure);
            app.TempF.Text = 'TempF';
            app.Humi = uilabel(app.UIFigure);
            app.Humi.Text = 'Humi';
            app.Air = uilabel(app.UIFigure);
            app.Air.Text = 'AP';
            app.PocketWeatherStationLabel = uilabel(app.UIFigure);
            app.PocketWeatherStationLabel.Text = 'Pocket Weather Station';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'សាស្ត្រាចារ្យដឹកនាំ : ស៊្រុន ចាន់ណារ៉េត';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '         សមាជិកក្រុម:       ១. រ៉េត សេងវិសុទ្ធ       ២. សារិ ពុទ្ធិពណ្ណរាយ       ៣. សាន មុន្នីឧសភារក្ស  ';
            app.AltitudeLabel = uilabel(app.UIFigure);
            app.AltitudeLabel.Text = 'Altitude';
            app.SL = uigauge(app.UIFigure, 'linear');
            app.SL.Limits = [0 20];
            app.Sea = uilabel(app.UIFigure);
            app.Sea.Text = 'SL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Weather_Base_Station02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end